classdef deeplabcut_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ShowFigureCheckBox              matlab.ui.control.CheckBox
        renameButton                    matlab.ui.control.Button
        settingsDropDown                matlab.ui.control.DropDown
        settingsDropDownLabel           matlab.ui.control.Label
        clearanalyzedButton             matlab.ui.control.Button
        analysisparametersPanel         matlab.ui.container.Panel
        param2Label                     matlab.ui.control.Label
        param4Label                     matlab.ui.control.Label
        param3Label                     matlab.ui.control.Label
        helpButton1                     matlab.ui.control.Button
        paramDropDown                   matlab.ui.control.DropDown
        primarybodypartLabel            matlab.ui.control.Label
        param4EditField                 matlab.ui.control.NumericEditField
        param3EditField                 matlab.ui.control.NumericEditField
        param2EditField                 matlab.ui.control.NumericEditField
        param1EditField                 matlab.ui.control.NumericEditField
        camerafpsLabel                  matlab.ui.control.Label
        objectdetectionparametersPanel  matlab.ui.container.Panel
        helpButton2                     matlab.ui.control.Button
        threshEditField                 matlab.ui.control.NumericEditField
        threshEditFieldLabel            matlab.ui.control.Label
        medfiltEditField                matlab.ui.control.NumericEditField
        medfiltEditField_2Label         matlab.ui.control.Label
        sizeEditField                   matlab.ui.control.NumericEditField
        sizeEditFieldLabel              matlab.ui.control.Label
        bkgdEditField                   matlab.ui.control.NumericEditField
        bkgdEditFieldLabel              matlab.ui.control.Label
        huewEditField                   matlab.ui.control.NumericEditField
        huewEditFieldLabel              matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        FilesanalyzedListBox            matlab.ui.control.ListBox
        FilesanalyzedListBoxLabel       matlab.ui.control.Label
        removeselectedButton            matlab.ui.control.Button
        deeplabcutanalysisLabel         matlab.ui.control.Label
        AddfilesButton                  matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        FilestoanalyzeListBox           matlab.ui.control.ListBox
        FilestoanalyzeListBoxLabel      matlab.ui.control.Label
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        ymaze_files
        ymaze_paths
        ymaze_files_analyzed
        ymazeparam
        
        open_files
        open_paths
        open_files_analyzed
        openparam
        
        object_files
        object_paths
        object_files_analyzed
        objectparam
        
        walking_files
        walking_paths
        walking_files_analyzed
        walkingparam
        
        user
        usernames
        settingsfile
        
        attribution_str
    end
    
    methods (Access = private)
        
        function update_files_list(app)
            
            %update files lists
            app.FilestoanalyzeListBox.Items = {};
            app.FilesanalyzedListBox.Items = {};
            
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    num_files = length(app.ymaze_files);
                    remove_inds = false(1,num_files);
                    if num_files==1
                        if isempty(strfind(app.ymaze_files{1},'YMaze')) %remove file if it isn't a YMaze DLC file
                            app.StatusLabel.Text = [app.ymaze_files{1} ' is not a valid DLC file for this analysis type'];     
                            figure(app.UIFigure)
                            pause(1)
                            remove_inds = true;
                        end
                    elseif num_files>1
                        for i = length(app.ymaze_files):-1:1
                            if any(strcmp(app.ymaze_files(1:i-1),app.ymaze_files(i))) %remove duplicates
                                app.StatusLabel.Text = [app.ymaze_files{1} ' is already included'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            elseif isempty(strfind(app.ymaze_files{i},'YMaze')) %remove files that aren't YMaze DLC files
                                app.StatusLabel.Text = [app.ymaze_files{1} ' is not a valid DLC file for this analysis type'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            end
                        end
                    end
                    app.ymaze_files(remove_inds) = [];
                    app.ymaze_paths(remove_inds) = [];
                    app.ymaze_files_analyzed(remove_inds) = [];
                    if isempty(app.ymaze_files)
                        app.FilestoanalyzeListBox.Items = {'no files added'};
                        app.FilestoanalyzeListBox.Enable = 0;
                    else
                        app.FilestoanalyzeListBox.Enable = 1;
                        for i = 1:length(app.ymaze_files)
                            if app.ymaze_files_analyzed(i)==0
                                app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.ymaze_files(i)];
                            else
                                app.FilesanalyzedListBox.Items = [app.FilesanalyzedListBox.Items, app.ymaze_files(i)];
                            end
                        end
                    end
                        
                case 'Open field'
                    app.objectdetectionparametersPanel.Visible = 0;
                    num_files = length(app.open_files);
                    remove_inds = false(1,num_files);
                    if num_files==1
                        if isempty(strfind(app.open_files{1},'BehaviorBox')) %remove file if it isn't a BehaviorBox DLC file
                            app.StatusLabel.Text = [app.open_files{1} ' is not a valid DLC file for this analysis type'];     
                            figure(app.UIFigure)
                            pause(1)
                            remove_inds = true;
                        end
                    elseif num_files>1
                        for i = length(app.open_files):-1:1
                            if any(strcmp(app.open_files(1:i-1),app.open_files(i))) %remove duplicates
                                app.StatusLabel.Text = [app.open_files{1} ' is already included'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            elseif isempty(strfind(app.open_files{i},'BehaviorBox')) %remove files that aren't BehaviorBox DLC files
                                app.StatusLabel.Text = [app.open_files{1} ' is not a valid DLC file for this analysis type'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            end
                        end
                    end
                    app.open_files(remove_inds) = [];
                    app.open_paths(remove_inds) = [];
                    app.open_files_analyzed(remove_inds) = [];
                    if isempty(app.open_files)
                        app.FilestoanalyzeListBox.Items = {'no files added'};
                        app.FilestoanalyzeListBox.Enable = 0;
                    else
                        app.FilestoanalyzeListBox.Enable = 1;
                        for i = 1:length(app.open_files)
                            if app.open_files_analyzed(i)==0
                                app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.open_files(i)];
                            else
                                app.FilesanalyzedListBox.Items = [app.FilesanalyzedListBox.Items, app.open_files(i)];
                            end
                        end
                    end
                    
                case 'Object interaction'
                    num_files = length(app.object_files);
                    remove_inds = false(1,num_files);
                    if num_files==1
                        if isempty(strfind(app.object_files{1},'BehaviorBox')) %remove file if it isn't a BehaviorBox DLC file
                            app.StatusLabel.Text = [app.object_files{1} ' is not a valid DLC file for this analysis type'];     
                            figure(app.UIFigure)
                            pause(1)
                            remove_inds = true;
                        end
                    elseif num_files>1
                        for i = length(app.object_files):-1:1
                            if any(strcmp(app.object_files(1:i-1),app.object_files(i))) %remove duplicates
                                app.StatusLabel.Text = [app.object_files{1} ' is already included'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            elseif isempty(strfind(app.object_files{i},'BehaviorBox')) %remove files that aren't BehaviorBox DLC files
                                app.StatusLabel.Text = [app.object_files{1} ' is not a valid DLC file for this analysis type'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            end
                        end
                    end
                    app.object_files(remove_inds) = [];
                    app.object_paths(remove_inds) = [];
                    app.object_files_analyzed(remove_inds) = [];
                    if isempty(app.object_files)
                        app.FilestoanalyzeListBox.Items = {'no files added'};
                        app.FilestoanalyzeListBox.Enable = 0;
                    else
                        app.FilestoanalyzeListBox.Enable = 1;
                        for i = 1:length(app.object_files)
                            if app.object_files_analyzed(i)==0
                                app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.object_files(i)];
                            else
                                app.FilesanalyzedListBox.Items = [app.FilesanalyzedListBox.Items, app.object_files(i)];
                            end
                        end
                    end
                    
                case 'Fixed walking'
                    app.objectdetectionparametersPanel.Visible = 0;
                    num_files = length(app.walking_files);
                    remove_inds = false(1,num_files);
                    if num_files==1
                        if isempty(strfind(app.walking_files{1},'CAM3')) %remove file if it isn't a fixed-walking DLC file
                            app.StatusLabel.Text = [app.walking_files{1} ' is not a valid DLC file for this analysis type'];     
                            figure(app.UIFigure)
                            pause(1)
                            remove_inds = true;
                        end
                    elseif num_files>1
                        for i = length(app.walking_files):-1:1
                            if any(strcmp(app.walking_files(1:i-1),app.walking_files(i))) %remove duplicates
                                app.StatusLabel.Text = [app.walking_files{1} ' is already included'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            elseif isempty(strfind(app.walking_files{i},'CAM3')) %remove files that aren't fixed-walking DLC files
                                app.StatusLabel.Text = [app.walking_files{1} ' is not a valid DLC file for this analysis type'];     
                                figure(app.UIFigure)
                                pause(1)
                                remove_inds(i) = true;
                            end
                        end
                    end
                    app.walking_files(remove_inds) = [];
                    app.walking_paths(remove_inds) = [];
                    app.walking_files_analyzed(remove_inds) = [];
                    if isempty(app.walking_files)
                        app.FilestoanalyzeListBox.Items = {'no files added'};
                        app.FilestoanalyzeListBox.Enable = 0;
                    else
                        app.FilestoanalyzeListBox.Enable = 1;
                        for i = 1:length(app.walking_files)
                            if app.walking_files_analyzed(i)==0
                                app.FilestoanalyzeListBox.Items = [app.FilestoanalyzeListBox.Items, app.walking_files(i)];
                            else
                                app.FilesanalyzedListBox.Items = [app.FilesanalyzedListBox.Items, app.walking_files(i)];
                            end
                        end
                    end
                    
            end
            
            %update parameters
            display_parameters(app)
            
            figure(app.UIFigure)
        end
        
        function update_parameters(app)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'                    
                    app.ymazeparam(app.user).bodypart = app.paramDropDown.Value;
                    app.ymazeparam(app.user).fps = app.param1EditField.Value;
                    app.ymazeparam(app.user).down_fps = app.param2EditField.Value;
                    app.ymazeparam(app.user).arm_threshold = app.param3EditField.Value;
                    app.ymazeparam(app.user).show_figure = app.ShowFigureCheckBox.Value;
                    
                case 'Open field'
                    app.openparam(app.user).bodypart = app.paramDropDown.Value;
                    app.openparam(app.user).fps = app.param1EditField.Value;
                    app.openparam(app.user).down_fps = app.param2EditField.Value;
                    app.openparam(app.user).close_threshold = app.param3EditField.Value;
                    app.openparam(app.user).show_figure = app.ShowFigureCheckBox.Value;
                    
                case 'Object interaction'
                    app.objectparam(app.user).bodypart = app.paramDropDown.Value;
                    app.objectparam(app.user).fps = app.param1EditField.Value;
                    app.objectparam(app.user).down_fps = app.param2EditField.Value;
                    app.objectparam(app.user).close_threshold = app.param3EditField.Value;
                    app.objectparam(app.user).view_cone = app.param4EditField.Value;
                    app.objectparam(app.user).show_figure = app.ShowFigureCheckBox.Value;
                    
                    app.objectparam(app.user).hue_weight = app.huewEditField.Value;
                    app.objectparam(app.user).bkgd_fraction = app.bkgdEditField.Value;
                    app.objectparam(app.user).zscore_threshold = app.threshEditField.Value;
                    app.objectparam(app.user).min_obj_size = app.sizeEditField.Value;
                    app.objectparam(app.user).med_filt = app.medfiltEditField.Value;
                    
                case 'Fixed walking'
                    app.walkingparam(app.user).bodypart = app.paramDropDown.Value;
                    app.walkingparam(app.user).fps = app.param1EditField.Value;
                    app.walkingparam(app.user).down_fps = app.param2EditField.Value;
            end
                    
            yparam = app.ymazeparam;
            oparam = app.openparam;
            bparam = app.objectparam;
            wparam = app.walkingparam;
            unames = app.usernames;
            save(app.settingsfile, 'yparam', 'oparam', 'bparam','wparam','unames')
        end
        
        function display_parameters(app)
            app.settingsDropDown.Items = app.usernames;
            
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    app.objectdetectionparametersPanel.Visible = 0;
                    app.param4EditField.Visible = 0;
                    app.param4Label.Visible = 0;
                    
                    app.paramDropDown.Value = app.ymazeparam(app.user).bodypart;
                    app.param1EditField.Value = app.ymazeparam(app.user).fps;
                    app.param2EditField.Value = app.ymazeparam(app.user).down_fps;
                    app.param3EditField.Value = app.ymazeparam(app.user).arm_threshold;
                    app.ShowFigureCheckBox.Value = app.ymazeparam(app.user).show_figure;
                    
                    app.param3Label.Text = 'in arm dist (cm)';
                    
                case 'Open field'
                    app.objectdetectionparametersPanel.Visible = 0;
                    app.param4EditField.Visible = 0;
                    app.param4Label.Visible = 0;
                    
                    app.paramDropDown.Value = app.openparam(app.user).bodypart;
                    app.param1EditField.Value = app.openparam(app.user).fps;
                    app.param2EditField.Value = app.openparam(app.user).down_fps;
                    app.param3EditField.Value = app.openparam(app.user).close_threshold;
                    app.ShowFigureCheckBox.Value = app.openparam(app.user).show_figure;
                    
                    app.param3Label.Text = 'close dist (cm)';
                    
                case 'Object interaction'
                    app.objectdetectionparametersPanel.Visible = 1;
                    app.param4EditField.Visible = 1;
                    app.param4Label.Visible = 1;
                    
                    app.paramDropDown.Value = app.objectparam(app.user).bodypart;
                    app.param1EditField.Value = app.objectparam(app.user).fps;
                    app.param2EditField.Value = app.objectparam(app.user).down_fps;
                    app.param3EditField.Value = app.objectparam(app.user).close_threshold;
                    app.param4EditField.Value = app.objectparam(app.user).view_cone;
                    app.ShowFigureCheckBox.Value = app.objectparam(app.user).show_figure;
                    
                    app.param3Label.Text = 'close dist (cm)';
                    app.param4Label.Text = 'view cone (deg)';
                    
                    app.huewEditField.Value = app.objectparam(app.user).hue_weight;
                    app.bkgdEditField.Value = app.objectparam(app.user).bkgd_fraction;
                    app.threshEditField.Value = app.objectparam(app.user).zscore_threshold;
                    app.sizeEditField.Value = app.objectparam(app.user).min_obj_size;
                    app.medfiltEditField.Value = app.objectparam(app.user).med_filt;
                    
                case 'Fixed walking'
                    app.objectdetectionparametersPanel.Visible = 0;
                    app.param4EditField.Visible = 0;
                    app.param4Label.Visible = 0;
                    
                    app.paramDropDown.Value = app.walkingparam(app.user).bodypart;
                    app.param1EditField.Value = app.walkingparam(app.user).fps;
                    app.param2EditField.Value = app.walkingparam(app.user).down_fps;
                    app.ShowFigureCheckBox.Value = app.openparam(app.user).show_figure;
                    
                    app.param3Label.Text = '';
            end
            app.StatusLabel.Text = ''; 
            figure(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.attribution_str = '-written 12/3/21, Matt Isaacson, mdi22';
            
            mfilepath = mfilename('fullpath');
            pwdpath = pwd;
            if exist(fullfile(pwdpath,'deeplabcut_analysis.exe'),'file')==2 || exist(fullfile(pwdpath,'deeplabcut_analysis.mlapp'),'file')==2
                app.settingsfile = fullfile(pwdpath,'dlc_analysis_settings.mat');
            elseif exist([mfilepath '.mlapp'],'file')==2
                mfilepath = fileparts(mfilepath);
                app.settingsfile = fullfile(mfilepath,'dlc_analysis_settings.mat');
            else
                app.settingsfile = 'C:\dlc_analysis_settings.mat';
            end
            app.user = 1;
            
            if exist(app.settingsfile,'file')==2
                load(app.settingsfile,'yparam','oparam','bparam','unames');
                app.ymazeparam = yparam;
                app.openparam = oparam;
                app.objectparam = bparam;
                app.usernames = unames;
                try %for backwards compatability (walkingparam was added later)
                    load(app.settingsfile,'wparam');
                    app.walkingparam = wparam;
                catch 
                    for u = 1:5
                        app.walkingparam(u).bodypart = 'toe';
                        app.walkingparam(u).fps = 120;
                        app.walkingparam(u).down_fps = 60;
                        app.walkingparam(u).show_figure = 1;
                    end
                end
            else %set defaults
                msgbox({['New settings file saved as ' app.settingsfile]});
                app.usernames = {'User 1', 'User 2', 'User 3', 'User 4', 'User 5'};
                for u = 1:5
                    app.ymazeparam(u).bodypart = 'head';
                    app.ymazeparam(u).fps = 30;
                    app.ymazeparam(u).down_fps = 10;
                    app.ymazeparam(u).arm_threshold = 8;
                    app.ymazeparam(u).show_figure = 1;
                    
                    app.openparam(u).bodypart = 'head';
                    app.openparam(u).fps = 30;
                    app.openparam(u).down_fps = 10;
                    app.openparam(u).close_threshold = 10;
                    app.openparam(u).show_figure = 1;
                    
                    app.objectparam(u).bodypart = 'head';
                    app.objectparam(u).fps = 30;
                    app.objectparam(u).down_fps = 10;
                    app.objectparam(u).close_threshold = 4;
                    app.objectparam(u).view_cone = 90;
                    app.objectparam(u).show_figure = 1;
                    
                    app.objectparam(u).hue_weight = 0.3;
                    app.objectparam(u).bkgd_fraction = 0.85;
                    app.objectparam(u).zscore_threshold = 3;
                    app.objectparam(u).min_obj_size = 5;
                    app.objectparam(u).med_filt = 1;
                    
                    app.walkingparam(u).bodypart = 'toe';
                    app.walkingparam(u).fps = 122.5;
                    app.walkingparam(u).down_fps = 30;
                    app.waklingparam(u).show_figure = 1;
                end
            end
            
            display_parameters(app);
            update_parameters(app);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: AddfilesButton
        function AddfilesButtonPushed(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    [new_files,new_paths] = uigetfile('*.h5',...
                        'Select One or More YMaze h5 Files', ...
                        'MultiSelect', 'on');
                    if ~new_paths(1)==0 %new_paths=0 if nothing was added
                        if iscell(new_files) %new_files is a cell array; turn new_paths from string to cell array
                            new_paths = repmat({new_paths},[1 length(new_files)]);
                        else %both are strings; make them into cells
                            new_files = {new_files};
                            new_paths = {new_paths};
                        end 
                        app.ymaze_files = [app.ymaze_files new_files];
                        app.ymaze_paths = [app.ymaze_paths new_paths];
                        app.ymaze_files_analyzed = [app.ymaze_files_analyzed zeros(1,length(new_files))];
                    end
                    
                case 'Open field'
                    [new_files,new_paths] = uigetfile('*.h5',...
                        'Select One or More BehaviorBox h5 Files', ...
                        'MultiSelect', 'on');
                    if ~new_paths(1)==0
                        if iscell(new_files) %new_files is a cell array; turn new_paths from string to cell array
                            new_paths = repmat({new_paths},[1 length(new_files)]);
                        else %both are strings; make them into cells
                            new_files = {new_files};
                            new_paths = {new_paths};
                        end 
                        app.open_files = [app.open_files new_files];
                        app.open_paths = [app.open_paths new_paths];
                        app.open_files_analyzed = [app.open_files_analyzed zeros(1,length(new_files))];
                    end
                    
                case 'Object interaction'
                    [new_files,new_paths] = uigetfile('*.h5',...
                        'Select One or More BehaviorBox h5 Files', ...
                        'MultiSelect', 'on');
                    if ~new_paths(1)==0
                        if iscell(new_files) %new_files is a cell array; turn new_paths from string to cell array
                            new_paths = repmat({new_paths},[1 length(new_files)]);
                        else %both are strings -- make them into cells
                            new_files = {new_files};
                            new_paths = {new_paths};
                        end 
                        app.object_files = [app.object_files new_files];
                        app.object_paths = [app.object_paths new_paths];
                        app.object_files_analyzed = [app.object_files_analyzed zeros(1,length(new_files))];
                    end
                    
                case 'Fixed walking'
                    [new_files,new_paths] = uigetfile('*.h5',...
                        'Select One or More CAM3 h5 Files', ...
                        'MultiSelect', 'on');
                    if ~new_paths(1)==0
                        if iscell(new_files) %new_files is a cell array; turn new_paths from string to cell array
                            new_paths = repmat({new_paths},[1 length(new_files)]);
                        else %both are strings; make them into cells
                            new_files = {new_files};
                            new_paths = {new_paths};
                        end 
                        app.walking_files = [app.walking_files new_files];
                        app.walking_paths = [app.walking_paths new_paths];
                        app.walking_files_analyzed = [app.walking_files_analyzed zeros(1,length(new_files))];
                    end
            end
            
            %update if new a new path was added (i.e. if at least one new file)
            s = whos("new_paths");
            if strcmp(s.class,'cell') %new_paths will be a cell if it isn't empty
                update_files_list(app)
            else
                figure(app.UIFigure)
            end
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            update_files_list(app)
        end

        % Button pushed function: removeselectedButton
        function removeselectedButtonPushed(app, event)
            for i = 1:length(app.FilestoanalyzeListBox.Value)
                curval = app.FilestoanalyzeListBox.Value(i);
                switch app.AnalysisTypeDropDown.Value
                    case 'Y-Maze'
                        ind_to_remove = find(strcmp(app.ymaze_files,curval));
                        app.ymaze_files(ind_to_remove) = [];
                        app.ymaze_paths(ind_to_remove) = [];
                        app.ymaze_files_analyzed(ind_to_remove) = [];
                        
                    case 'Open field'
                        ind_to_remove = find(strcmp(app.open_files,curval));
                        app.open_files(ind_to_remove) = [];
                        app.open_paths(ind_to_remove) = [];
                        app.open_files_analyzed(ind_to_remove) = [];
                        
                    case 'Object interaction'
                        ind_to_remove = find(strcmp(app.object_files,curval));
                        app.object_files(ind_to_remove) = [];
                        app.object_paths(ind_to_remove) = [];
                        app.object_files_analyzed(ind_to_remove) = [];
                        
                    case 'Fixed walking'
                        ind_to_remove = find(strcmp(app.walking_files,curval));
                        app.walking_files(ind_to_remove) = [];
                        app.walking_paths(ind_to_remove) = [];
                        app.walking_files_analyzed(ind_to_remove) = [];
                end
            end
            
            update_files_list(app)
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    for i = 1:length(app.ymaze_files)
                        if app.ymaze_files_analyzed(i)==0
                            app.StatusLabel.Text = 'Analyzing...';
                            pause(0.01)
                            try
                                analyze_ymaze(fullfile(app.ymaze_paths{i},app.ymaze_files{i}),app.ymazeparam(app.user));
                                app.ymaze_files_analyzed(i) = 1;
                                update_files_list(app);
                                pause(0.01)
                                app.StatusLabel.Text = ''; 
                            catch ME
                                app.ymaze_files_analyzed(i) = 0;
                                app.StatusLabel.Text = {['error during analysis of ' app.ymaze_files{i}], ME.message};
                                figure(app.UIFigure)
                                pause(3)
                            end
                        end
                    end
                case 'Open field'
                    for i = 1:length(app.open_files)
                        if app.open_files_analyzed(i)==0
                            app.StatusLabel.Text = 'Analyzing...';
                            pause(0.01)
                            try
                                analyze_openfield(fullfile(app.open_paths{i},app.open_files{i}),app.openparam(app.user));
                                app.open_files_analyzed(i) = 1;
                                update_files_list(app);
                                pause(0.01)
                                app.StatusLabel.Text = ''; 
                            catch ME
                                app.open_files_analyzed(i) = 0;
                                app.StatusLabel.Text = {['error during analysis of ' app.open_files{i}], ME.message};
                                figure(app.UIFigure)
                                pause(3)
                            end
                        end
                    end
                case 'Object interaction'
                    for i = 1:length(app.object_files)
                        if app.object_files_analyzed(i)==0
                            app.StatusLabel.Text = 'Analyzing...';     
                            pause(0.01)
                            try
                                analyze_objectinteraction(fullfile(app.object_paths{i},app.object_files{i}),app.objectparam(app.user));
                                app.object_files_analyzed(i) = 1;
                                update_files_list(app);
                                pause(0.01)
                                app.StatusLabel.Text = ''; 
                            catch ME
                                app.object_files_analyzed(i) = 0;
                                app.StatusLabel.Text = {['error during analysis of ' app.object_files{i}], ME.message};
                                figure(app.UIFigure)
                                pause(3)
                            end
                        end
                    end
                case 'Fixed walking'
                    for i = 1:length(app.walking_files)
                        if app.walking_files_analyzed(i)==0
                            app.StatusLabel.Text = 'Analyzing...';
                            pause(0.01)
                            try
                                analyze_fixedwalking(fullfile(app.walking_paths{i},app.walking_files{i}),app.walkingparam(app.user));
                                app.walking_files_analyzed(i) = 1;
                                update_files_list(app);
                                pause(0.01)
                                app.StatusLabel.Text = ''; 
                            catch ME
                                app.walking_files_analyzed(i) = 0;
                                app.StatusLabel.Text = {['error during analysis of ' app.walking_files{i}], ME.message};
                                figure(app.UIFigure)
                                pause(3)
                            end
                        end
                    end
            end
        end

        % Button pushed function: helpButton2
        function helpButton2Pushed(app, event)
            msgbox({'Object detection parameters can be adjusted if object outlines are ';...
                'inaccurate. Use "...DetectionDetails.png" and the notes below to optimize ';...
                'these parameters for your video.';...
                '';...
                '';...
                'BKGD: the fraction of pixels inside the box which aren''t object pixels. '; ...
                '  For small objects, the bkgd can be larger (e.g. 0.9, 0.95), and vice '; ...
                '  versa. Better to underestimate this than overstimate it (i.e. use ';...
                '  smaller bkgd values). If any object pixels are colored white in panel 3,';...
                '  try using a smaller bkgd value.'; ...
                '';...
                'HUE W: this value specifies the weight of hue (vs level) to use for ';...
                '  detecting objects over the background. Values closer to 1 will use the '; ...
                '  hue more to detect objects (good for bright, not-red objects, with ';...
                '  higher quality detection in panel 4), while values closer to 0 will use'; ...
                '  the darker-levels more (good for dark objects, with higher quality ';...
                '  detection in panel 5, but bad for shadows).';...
                '';...
                'MED FILT: the size (in cm) to median filter the combined z-score image ';...
                '  in panel 6. Consider using larger values if background noise is too '; ...
                '  large, and smaller values if actual object pixels are being excluded.'; ...
                '';...
                'THRESHOLD: the z-score threshold used to convert the combined z-score '; ...
                '  image in panel 6, to the objects mask in panel 7. Try a larger threshold ';...
                '  if too much noise is being included; smaller if object pixels are being ';...
                '  excluded.';...
                ''; ...
                'SIZE: the minimum size (in cm^2) to consider connected components to';...
                '  be objects. Use larger values to prevent small, noisy areas from being ';...
                '  labelled as objects in panel 8, or smaller values if a real object is ';...
                '  being excluded in panel 8 (objects may also be excluded by a too-large '; ...
                '  threshold).'; ...
                '';...
                app.attribution_str;...
                ''},'about object detection parameters');
        end

        % Button pushed function: clearanalyzedButton
        function clearanalyzedButtonPushed(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    ind_to_remove = logical(app.ymaze_files_analyzed);
                    app.ymaze_files(ind_to_remove) = [];
                    app.ymaze_paths(ind_to_remove) = [];
                    app.ymaze_files_analyzed(ind_to_remove) = [];
                    
                case 'Open field'
                    ind_to_remove = logical(app.open_files_analyzed);
                    app.open_files(ind_to_remove) = [];
                    app.open_paths(ind_to_remove) = [];
                    app.open_files_analyzed(ind_to_remove) = [];
                    
                case 'Object interaction'
                    ind_to_remove = logical(app.object_files_analyzed);
                    app.object_files(ind_to_remove) = [];
                    app.object_paths(ind_to_remove) = [];
                    app.object_files_analyzed(ind_to_remove) = [];
                    
                case 'Fixed walking'
                    ind_to_remove = logical(app.walking_files_analyzed);
                    app.walking_files(ind_to_remove) = [];
                    app.walking_paths(ind_to_remove) = [];
                    app.walking_files_analyzed(ind_to_remove) = [];
            end
            update_files_list(app)
        end

        % Value changed function: bkgdEditField
        function bkgdEditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: huewEditField
        function huewEditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: sizeEditField
        function sizeEditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: medfiltEditField
        function medfiltEditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: threshEditField
        function threshEditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: param1EditField
        function param1EditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: param2EditField
        function param2EditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: param3EditField
        function param3EditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: param4EditField
        function param4EditFieldValueChanged(app, event)
            update_parameters(app)
        end

        % Value changed function: paramDropDown
        function paramDropDownValueChanged(app, event)
            update_parameters(app)
        end

        % Button pushed function: helpButton1
        function helpButton1Pushed(app, event)
            switch app.AnalysisTypeDropDown.Value
                case 'Y-Maze'
                    msgbox({'The data analysis parameters described below determine how some ';...
                    'aspects of the data is processed, according to the selected analysis type.';...
                    '';...
                    '';...
                    'BODYPART: the bodypart of the mouse (e.g. nose, tail) which is used to '; ...
                    '  calculate the mouse''s distance to other aspects of the environment '; ...
                    '  (e.g. walls, objects, arm ends). For the nose and tail bodyparts, these ';...
                    '  are directly tracked by deeplabcut. The "head" bodypart is estimated as ';...
                    '  the center of the nose and ears. The "body" bodypart is estimated as the '; ...
                    '  center of the head and body. The "head and tail" bodypart uses both '; ...
                    '  head and tail (both have to meet the distance requirement).'; ...
                    '';...
                    'FPS: the framerate (frames/second) of the video processed through ';...
                    '  deeplabcut.';...
                    '';...
                    'DOWN FPS: the effective framerate of the tracking data after a rolling ';...
                    '  average is performed to smooth the data and remove tracking errors.';...
                    '';...
                    'IN ARM DIST: the minimum distance (in cm) from the ymaze center toward an'; ...
                    '  arm end to determine whether the mouse (according to the position of the ';...
                    '  primary bodypart) is inside the arm or not.';...
                    '';...
                    app.attribution_str;...
                    ''},'about data analysis parameters');
                    
                case 'Open field'
                    msgbox({'The data analysis parameters described below determine how some ';...
                    'aspects of the data is processed, according to the selected analysis type.';...
                    '';...
                    '';...
                    'BODYPART: the bodypart of the mouse (e.g. nose, tail) which is used to '; ...
                    '  calculate the mouse''s distance to other aspects of the environment '; ...
                    '  (e.g. walls, objects, arm ends). For the nose and tail bodyparts, these ';...
                    '  are directly tracked by deeplabcut. The "head" bodypart is estimated as ';...
                    '  the center of the nose and ears. The "body" bodypart is estimated as the '; ...
                    '  center of the head and body. The "head and tail" bodypart uses both '; ...
                    '  head and tail (both have to meet the distance requirement).'; ...
                    '';...
                    'FPS: the framerate (frames/second) of the video processed through ';...
                    '  deeplabcut.';...
                    '';...
                    'DOWN FPS: the effective framerate of the tracking data after a rolling ';...
                    '  average is performed to smooth the data and remove tracking errors.';...
                    '';...
                    'CLOSE DIST: the distance (in cm) from the nearest wall to determine '; ...
                    '  whether the mouse (according to the position of the primary bodypart) is';...
                    '  close to the wall or not.';...
                    '';...
                    app.attribution_str;...
                    ''},'about data analysis parameters');
                    
                case 'Object interaction'
                    msgbox({'The data analysis parameters described below determine how some ';...
                    'aspects of the data is processed, according to the selected analysis type.';...
                    '';...
                    '';...
                    'BODYPART: the bodypart of the mouse (e.g. nose, tail) which is used to '; ...
                    '  calculate the mouse''s distance to other aspects of the environment '; ...
                    '  (e.g. walls, objects, arm ends). For the nose and tail bodyparts, these ';...
                    '  are directly tracked by deeplabcut. The "head" bodypart is estimated as ';...
                    '  the center of the nose and ears. The "body" bodypart is estimated as the '; ...
                    '  center of the head and body. The "head and tail" bodypart uses both '; ...
                    '  head and tail (both have to meet the distance requirement).'; ...
                    '';...
                    'FPS: the framerate (frames/second) of the video processed through ';...
                    '  deeplabcut.';...
                    '';...
                    'DOWN FPS: the effective framerate of the tracking data after a rolling ';...
                    '  average is performed to smooth the data and remove tracking errors.';...
                    '';...
                    'CLOSE DIST: the distance (in cm) between the mouse and each object to '; ...
                    '  determine whether the mouse (according to the position of the primary ';...
                    '  bodypart) is close to the object or not.';...
                    '';...
                    'VIEW CONE: the viewing cone angle (i.e. the horizontal FOV) from the ';...
                    '  perspective of the mouse to determine whether the mouse is oriented to '; ...
                    '  an object or not. For example, view cone=190 means that the mouse is '; ...
                    '  oriented to an object if it''s in the front half of the mouse''s visual ';...
                    '  field.';...
                    '';...
                    app.attribution_str;...
                    ''},'about data analysis parameters');
                    
                case 'Fixed walking'
                    msgbox({'The data analysis parameters described below determine how some ';...
                    'aspects of the data is processed, according to the selected analysis type.';...
                    '';...
                    '';...
                    'BODYPART: the bodypart of the mouse (e.g. heel, toe) which is used to '; ...
                    '  determine when a new gait cycle starts. The forward/backward (i.e. x'; ...
                    '  coordinate) movement of the primary bodypart is modulated very strongly ';...
                    '  during walking/running. When this bodypart is in it''s farthest forward ';...
                    '  or backward position, the gait cycle is switching between the stance '; ...
                    '  and swing phase of the gait cycle.'; ...
                    '';...
                    'FPS: the framerate (frames/second) of the video processed through ';...
                    '  deeplabcut.';...
                    '';...
                    'DOWN FPS: the effective framerate of the tracking data after a rolling ';...
                    '  average is performed to smooth the data and remove tracking errors.';...
                    '';...
                    app.attribution_str;...
                    ''},'about data analysis parameters');
                    
            end
        end

        % Value changed function: settingsDropDown
        function settingsDropDownValueChanged(app, event)
            value = app.settingsDropDown.Value;
            items = app.settingsDropDown.Items;
            numitems = length(items);
            matches = cellfun(@strcmp,items,repmat({value},[1 numitems])); %looks for value in items
            app.user = find(matches,1); %logical to index
            
            display_parameters(app)
        end

        % Button pushed function: renameButton
        function renameButtonPushed(app, event)
            name = inputdlg('Enter a new name','Rename User',[1 20],app.usernames(app.user)); ...
            if ~isempty(name)
                app.usernames(app.user) = name;
                app.settingsDropDown.Items = app.usernames;
                app.settingsDropDown.Value = app.usernames{app.user};
                update_parameters(app)
            end
        end

        % Value changed function: ShowFigureCheckBox
        function ShowFigureCheckBoxValueChanged(app, event)
            update_parameters(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisTypeDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.UIFigure);
            app.AnalysisTypeDropDown.Items = {'Y-Maze', 'Open field', 'Object interaction', 'Fixed walking'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Y-Maze';
            app.FilestoanalyzeListBoxLabel = uilabel(app.UIFigure);
            app.FilestoanalyzeListBoxLabel.Text = 'Files to analyze';
            app.FilestoanalyzeListBox = uilistbox(app.UIFigure);
            app.FilestoanalyzeListBox.Items = {'no files added'};
            app.FilestoanalyzeListBox.Multiselect = 'on';
            app.FilestoanalyzeListBox.Enable = 'off';
            app.FilestoanalyzeListBox.Value = {};
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.AddfilesButton = uibutton(app.UIFigure, 'push');
            app.AddfilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddfilesButtonPushed, true);
            app.AddfilesButton.Text = 'Add files';
            app.deeplabcutanalysisLabel = uilabel(app.UIFigure);
            app.deeplabcutanalysisLabel.Text = 'deeplabcut analysis';
            app.removeselectedButton = uibutton(app.UIFigure, 'push');
            app.removeselectedButton.ButtonPushedFcn = createCallbackFcn(app, @removeselectedButtonPushed, true);
            app.removeselectedButton.Text = {'remove'; 'selected'};
            app.FilesanalyzedListBoxLabel = uilabel(app.UIFigure);
            app.FilesanalyzedListBoxLabel.Text = 'Files analyzed';
            app.FilesanalyzedListBox = uilistbox(app.UIFigure);
            app.FilesanalyzedListBox.Items = {};
            app.FilesanalyzedListBox.Enable = 'off';
            app.FilesanalyzedListBox.Value = {};
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = '';
            app.objectdetectionparametersPanel = uipanel(app.UIFigure);
            app.objectdetectionparametersPanel.Title = 'object detection parameters';
            app.objectdetectionparametersPanel.Visible = 'off';
            app.huewEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.huewEditFieldLabel.Text = 'hue w';
            app.huewEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.huewEditField.Limits = [0 1];
            app.huewEditField.ValueChangedFcn = createCallbackFcn(app, @huewEditFieldValueChanged, true);
            app.huewEditField.Value = 0.3;
            app.bkgdEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.bkgdEditFieldLabel.Text = 'bkgd';
            app.bkgdEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.bkgdEditField.Limits = [0 1];
            app.bkgdEditField.ValueChangedFcn = createCallbackFcn(app, @bkgdEditFieldValueChanged, true);
            app.bkgdEditField.Value = 0.85;
            app.sizeEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.sizeEditFieldLabel.Text = 'size';
            app.sizeEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.sizeEditField.Limits = [0.5 500];
            app.sizeEditField.ValueChangedFcn = createCallbackFcn(app, @sizeEditFieldValueChanged, true);
            app.sizeEditField.Value = 5;
            app.medfiltEditField_2Label = uilabel(app.objectdetectionparametersPanel);
            app.medfiltEditField_2Label.Text = 'med filt';
            app.medfiltEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.medfiltEditField.Limits = [0 10];
            app.medfiltEditField.ValueChangedFcn = createCallbackFcn(app, @medfiltEditFieldValueChanged, true);
            app.medfiltEditField.Value = 1;
            app.threshEditFieldLabel = uilabel(app.objectdetectionparametersPanel);
            app.threshEditFieldLabel.Text = 'thresh';
            app.threshEditField = uieditfield(app.objectdetectionparametersPanel, 'numeric');
            app.threshEditField.Limits = [0 Inf];
            app.threshEditField.ValueChangedFcn = createCallbackFcn(app, @threshEditFieldValueChanged, true);
            app.threshEditField.Value = 3;
            app.helpButton2 = uibutton(app.objectdetectionparametersPanel, 'push');
            app.helpButton2.ButtonPushedFcn = createCallbackFcn(app, @helpButton2Pushed, true);
            app.helpButton2.Text = 'help';
            app.analysisparametersPanel = uipanel(app.UIFigure);
            app.analysisparametersPanel.Title = 'analysis parameters';
            app.camerafpsLabel = uilabel(app.analysisparametersPanel);
            app.camerafpsLabel.Text = 'camera fps';
            app.param1EditField = uieditfield(app.analysisparametersPanel, 'numeric');
            app.param1EditField.Limits = [0.1 1000];
            app.param1EditField.ValueChangedFcn = createCallbackFcn(app, @param1EditFieldValueChanged, true);
            app.param1EditField.Value = 30;
            app.param2EditField = uieditfield(app.analysisparametersPanel, 'numeric');
            app.param2EditField.Limits = [0.1 1000];
            app.param2EditField.ValueChangedFcn = createCallbackFcn(app, @param2EditFieldValueChanged, true);
            app.param2EditField.Value = 10;
            app.param3EditField = uieditfield(app.analysisparametersPanel, 'numeric');
            app.param3EditField.Limits = [0.1 1000];
            app.param3EditField.ValueChangedFcn = createCallbackFcn(app, @param3EditFieldValueChanged, true);
            app.param3EditField.Value = 4;
            app.param4EditField = uieditfield(app.analysisparametersPanel, 'numeric');
            app.param4EditField.Limits = [0.1 1000];
            app.param4EditField.ValueChangedFcn = createCallbackFcn(app, @param4EditFieldValueChanged, true);
            app.param4EditField.Value = 90;
            app.primarybodypartLabel = uilabel(app.analysisparametersPanel);
            app.primarybodypartLabel.Text = 'primary bodypart';
            app.paramDropDown = uidropdown(app.analysisparametersPanel);
            app.paramDropDown.Items = {'nose', 'head', 'body', 'tail', 'head and tail', 'heel', 'toe'};
            app.paramDropDown.ValueChangedFcn = createCallbackFcn(app, @paramDropDownValueChanged, true);
            app.paramDropDown.Value = 'head';
            app.helpButton1 = uibutton(app.analysisparametersPanel, 'push');
            app.helpButton1.ButtonPushedFcn = createCallbackFcn(app, @helpButton1Pushed, true);
            app.helpButton1.Text = 'help';
            app.param3Label = uilabel(app.analysisparametersPanel);
            app.param3Label.Text = 'close dist (cm)';
            app.param4Label = uilabel(app.analysisparametersPanel);
            app.param4Label.Text = 'view cone (deg)';
            app.param2Label = uilabel(app.analysisparametersPanel);
            app.param2Label.Text = 'down fps';
            app.clearanalyzedButton = uibutton(app.UIFigure, 'push');
            app.clearanalyzedButton.ButtonPushedFcn = createCallbackFcn(app, @clearanalyzedButtonPushed, true);
            app.clearanalyzedButton.Text = {'clear'; 'analyzed'};
            app.settingsDropDownLabel = uilabel(app.UIFigure);
            app.settingsDropDownLabel.Text = 'settings';
            app.settingsDropDown = uidropdown(app.UIFigure);
            app.settingsDropDown.Items = {'User 1', 'User 2', 'User 3'};
            app.settingsDropDown.ValueChangedFcn = createCallbackFcn(app, @settingsDropDownValueChanged, true);
            app.settingsDropDown.Value = 'User 1';
            app.renameButton = uibutton(app.UIFigure, 'push');
            app.renameButton.ButtonPushedFcn = createCallbackFcn(app, @renameButtonPushed, true);
            app.renameButton.Text = 'rename';
            app.ShowFigureCheckBox = uicheckbox(app.UIFigure);
            app.ShowFigureCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFigureCheckBoxValueChanged, true);
            app.ShowFigureCheckBox.Text = 'Show results figure when finished';
            app.ShowFigureCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = deeplabcut_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end